classdef binocularMRI < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		BinocularMRIUIFigure            matlab.ui.Figure
		BinocularMRIStimuluatorV107Panel  matlab.ui.container.Panel
		SFModTimeEditField              matlab.ui.control.NumericEditField
		SFModTimeEditFieldLabel         matlab.ui.control.Label
		PixelDensityEditField           matlab.ui.control.NumericEditField
		PixelDensityEditFieldLabel      matlab.ui.control.Label
		DistancecmEditField             matlab.ui.control.NumericEditField
		DistancecmEditFieldLabel        matlab.ui.control.Label
		TestButton                      matlab.ui.control.Button
		SubjectNameEditField            matlab.ui.control.EditField
		SubjectNameEditFieldLabel       matlab.ui.control.Label
		TemporalFrequencyEditField      matlab.ui.control.NumericEditField
		TemporalFrequencyEditFieldLabel  matlab.ui.control.Label
		debugCheckBox                   matlab.ui.control.CheckBox
		RepeatsEditField                matlab.ui.control.NumericEditField
		RepeatsEditFieldLabel           matlab.ui.control.Label
		StimInterstimTimesEditField     matlab.ui.control.EditField
		StimInterstimTimesEditFieldLabel  matlab.ui.control.Label
		SFModEditField                  matlab.ui.control.NumericEditField
		SFModEditFieldLabel             matlab.ui.control.Label
		AngleModEditField               matlab.ui.control.NumericEditField
		AngleModEditFieldLabel          matlab.ui.control.Label
		FlashRateEditField              matlab.ui.control.NumericEditField
		FlashRateEditFieldLabel         matlab.ui.control.Label
		SpatialFrequencyEditField       matlab.ui.control.NumericEditField
		SpatialFrequencyEditFieldLabel  matlab.ui.control.Label
		StimulusTypeListBox             matlab.ui.control.ListBox
		StimulusTypeListBoxLabel        matlab.ui.control.Label
		RightRGBEditField               matlab.ui.control.EditField
		RightRGBEditFieldLabel          matlab.ui.control.Label
		LeftRGBEditField                matlab.ui.control.EditField
		LeftRGBEditFieldLabel           matlab.ui.control.Label
		BackgroundEditField             matlab.ui.control.EditField
		BackgroundEditFieldLabel        matlab.ui.control.Label
		StereoModeEditField             matlab.ui.control.NumericEditField
		StereoModeEditFieldLabel        matlab.ui.control.Label
		QuitButton                      matlab.ui.control.Button
		RunButton                       matlab.ui.control.Button
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Button pushed function: RunButton, TestButton
		function RunButtonPushed(app, event)

			if strcmpi(event.Source.Text,'Test')
				in.test = true;
			else
				in.test = false;
			end
			
			in.name = app.SubjectNameEditField.Value;
			in.distance = app.DistancecmEditField.Value;
			in.ppcm = app.PixelDensityEditField.Value;
			in.stereoMode = app.StereoModeEditField.Value;
			in.bg = str2num(app.BackgroundEditField.Value);
			in.left = str2num(app.LeftRGBEditField.Value);
			in.right = str2num(app.RightRGBEditField.Value);
			in.type = app.StimulusTypeListBox.Value;
			in.sf = app.SpatialFrequencyEditField.Value;
			in.tf = app.TemporalFrequencyEditField.Value;
			in.flash = app.FlashRateEditField.Value;
			in.anglemod = app.AngleModEditField.Value;
			in.sfmod = app.SFModEditField.Value;
			in.sfmodtime = app.SFModTimeEditField.Value
			in.repeats =  app.RepeatsEditField.Value;
			in.times = str2num(app.StimInterstimTimesEditField.Value);
			in.debug = app.debugCheckBox.Value;

			binocularStimulation(in);
		end

		% Button pushed function: QuitButton
		function QuitButtonPushed(app, event)
			delete(app);
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.BinocularMRIUIFigure = uifigure('Visible', 'off');
			app.BinocularMRIUIFigure.Name = 'Binocular MRI';
			app.BinocularMRIStimuluatorV107Panel = uipanel(app.BinocularMRIUIFigure);
			app.BinocularMRIStimuluatorV107Panel.Title = 'Binocular MRI Stimuluator V1.07';
			app.RunButton = uibutton(app.BinocularMRIStimuluatorV107Panel, 'push');
			app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
			app.RunButton.Text = 'Run!';
			app.QuitButton = uibutton(app.BinocularMRIStimuluatorV107Panel, 'push');
			app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
			app.QuitButton.Text = 'Quit';
			app.StereoModeEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.StereoModeEditFieldLabel.Text = 'Stereo Mode';
			app.StereoModeEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'numeric');
			app.StereoModeEditField.Value = 8;
			app.BackgroundEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.BackgroundEditFieldLabel.Text = 'Background';
			app.BackgroundEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'text');
			app.BackgroundEditField.Value = '0  0  0';
			app.LeftRGBEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.LeftRGBEditFieldLabel.Text = 'Left RGB';
			app.LeftRGBEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'text');
			app.LeftRGBEditField.Value = '0.9  0  0';
			app.RightRGBEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.RightRGBEditFieldLabel.Text = 'Right RGB';
			app.RightRGBEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'text');
			app.RightRGBEditField.Value = '0  0.3  1';
			app.StimulusTypeListBoxLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.StimulusTypeListBoxLabel.Text = 'Stimulus Type';
			app.StimulusTypeListBox = uilistbox(app.BinocularMRIStimuluatorV107Panel);
			app.StimulusTypeListBox.Items = {'Checkerboard', 'Spiral', 'Radial', 'Circular'};
			app.StimulusTypeListBox.Value = 'Checkerboard';
			app.SpatialFrequencyEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.SpatialFrequencyEditFieldLabel.Text = 'Spatial Frequency';
			app.SpatialFrequencyEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'numeric');
			app.SpatialFrequencyEditField.Value = 0.59;
			app.FlashRateEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.FlashRateEditFieldLabel.Text = 'Flash Rate';
			app.FlashRateEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'numeric');
			app.AngleModEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.AngleModEditFieldLabel.Text = 'Angle Mod';
			app.AngleModEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'numeric');
			app.AngleModEditField.Value = 0.1;
			app.SFModEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.SFModEditFieldLabel.Text = 'SF Mod';
			app.SFModEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'numeric');
			app.SFModEditField.Value = 0.81;
			app.StimInterstimTimesEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.StimInterstimTimesEditFieldLabel.Text = 'Stim / Inter-stim Time (s)';
			app.StimInterstimTimesEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'text');
			app.StimInterstimTimesEditField.Value = '16 16';
			app.RepeatsEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.RepeatsEditFieldLabel.Text = 'Repeats';
			app.RepeatsEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'numeric');
			app.RepeatsEditField.Value = 5;
			app.debugCheckBox = uicheckbox(app.BinocularMRIStimuluatorV107Panel);
			app.debugCheckBox.Text = 'debug';
			app.TemporalFrequencyEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.TemporalFrequencyEditFieldLabel.Text = 'Temporal Frequency';
			app.TemporalFrequencyEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'numeric');
			app.SubjectNameEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.SubjectNameEditFieldLabel.Text = 'Subject Name';
			app.SubjectNameEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'text');
			app.SubjectNameEditField.Value = 'Test';
			app.TestButton = uibutton(app.BinocularMRIStimuluatorV107Panel, 'push');
			app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
			app.TestButton.Text = 'Test';
			app.DistancecmEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
			app.DistancecmEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'numeric');
			app.DistancecmEditField.Value = 173.1;
			app.PixelDensityEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.PixelDensityEditFieldLabel.Text = 'Pixel Density';
			app.PixelDensityEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'numeric');
			app.PixelDensityEditField.Value = 35.2;
			app.SFModTimeEditFieldLabel = uilabel(app.BinocularMRIStimuluatorV107Panel);
			app.SFModTimeEditFieldLabel.Text = 'SF Mod Time';
			app.SFModTimeEditField = uieditfield(app.BinocularMRIStimuluatorV107Panel, 'numeric');
			app.SFModTimeEditField.Value = 0.02;
			% Show the figure after all components are created
			app.BinocularMRIUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = binocularMRI

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.BinocularMRIUIFigure)
			else

				% Focus the running singleton app
				figure(runningApp.BinocularMRIUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.BinocularMRIUIFigure)
		end
	end
end